classdef Equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileNameArea_2                matlab.ui.control.TextArea
        Label_2                       matlab.ui.control.Label
        ExportButton                  matlab.ui.control.Button
        SpectrumDropDown              matlab.ui.control.DropDown
        SpectrumDropDownLabel         matlab.ui.control.Label
        MATLABGUIAUDIOEQUALIZERLabel  matlab.ui.control.Label
        PlayButton                    matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        ResumePauseButton             matlab.ui.control.Button
        BrowseButton                  matlab.ui.control.Button
        FileNameArea                  matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        High4kHzSlider                matlab.ui.control.Slider
        High4kHzSliderLabel           matlab.ui.control.Label
        Band24kHzSlider               matlab.ui.control.Slider
        Band24kHzLabel                matlab.ui.control.Label
        Band052kHzSlider              matlab.ui.control.Slider
        Band052kHzLabel               matlab.ui.control.Label
        Band250500HzSlider            matlab.ui.control.Slider
        Band250500HzLabel             matlab.ui.control.Label
        EditField_5                   matlab.ui.control.NumericEditField
        EditField_4                   matlab.ui.control.NumericEditField
        EditField_3                   matlab.ui.control.NumericEditField
        EditField_2                   matlab.ui.control.NumericEditField
        EditField                     matlab.ui.control.NumericEditField
        Low250HzSlider_6              matlab.ui.control.Slider
        Low250HzLabel                 matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BrowseButton, FileNameArea
        function BrowseButtonPushed(app, event)
            
            [file,path] = uigetfile({'*.wav'},'Select file .wav')
            app.FileNameArea.Value = file
            
            [y1, Fs1] = audioread(fullfile(path,file));
            player1 = audioplayer(y1, Fs1);
            playblocking(player1)

            app.FileNameArea_2.Value = num2str(Fs1);        % Display sample rate in label
            plot(app.UIAxes, y1);
        end

        % Callback function
        function TextAreaValueChanged(app, event)

         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, ' ')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Magnitude')
            app.UIAxes3.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes3.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Signal')
            xlabel(app.UIAxes, 'Frequency')
            ylabel(app.UIAxes, 'Magnitude')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Output Signal')
            xlabel(app.UIAxes_2, 'Frequency')
            ylabel(app.UIAxes_2, 'Magnitude')
            app.UIAxes_2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_2.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.UIAxes_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.Low250HzLabel = uilabel(app.UIFigure);
            app.Low250HzLabel.Text = 'Low(<250 Hz)';
            app.Low250HzSlider_6 = uislider(app.UIFigure);
            app.Low250HzSlider_6.Limits = [-100 100];
            app.Low250HzSlider_6.MajorTicks = [];
            app.Low250HzSlider_6.MinorTicks = [];
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.Band250500HzLabel = uilabel(app.UIFigure);
            app.Band250500HzLabel.Text = 'Band(250-500 Hz)';
            app.Band250500HzSlider = uislider(app.UIFigure);
            app.Band250500HzSlider.Limits = [-100 100];
            app.Band250500HzSlider.MajorTicks = [];
            app.Band250500HzSlider.MinorTicks = [];
            app.Band052kHzLabel = uilabel(app.UIFigure);
            app.Band052kHzLabel.Text = 'Band(0.5-2 kHz)';
            app.Band052kHzSlider = uislider(app.UIFigure);
            app.Band052kHzSlider.Limits = [-100 100];
            app.Band052kHzSlider.MajorTicks = [];
            app.Band052kHzSlider.MinorTicks = [];
            app.Band24kHzLabel = uilabel(app.UIFigure);
            app.Band24kHzLabel.Text = 'Band(2-4 kHz)';
            app.Band24kHzSlider = uislider(app.UIFigure);
            app.Band24kHzSlider.Limits = [-100 100];
            app.Band24kHzSlider.MajorTicks = [];
            app.Band24kHzSlider.MinorTicks = [];
            app.High4kHzSliderLabel = uilabel(app.UIFigure);
            app.High4kHzSliderLabel.Text = 'High(>4 kHz)';
            app.High4kHzSlider = uislider(app.UIFigure);
            app.High4kHzSlider.Limits = [-100 100];
            app.High4kHzSlider.MajorTicks = [];
            app.High4kHzSlider.MinorTicks = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.FileNameArea = uitextarea(app.UIFigure);
            app.FileNameArea.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ResumePauseButton = uibutton(app.UIFigure, 'push');
            app.ResumePauseButton.Text = 'Resume/Pause';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.Text = 'Play';
            app.MATLABGUIAUDIOEQUALIZERLabel = uilabel(app.UIFigure);
            app.MATLABGUIAUDIOEQUALIZERLabel.Text = 'MATLAB GUI AUDIO EQUALIZER';
            app.SpectrumDropDownLabel = uilabel(app.UIFigure);
            app.SpectrumDropDownLabel.Text = 'Spectrum';
            app.SpectrumDropDown = uidropdown(app.UIFigure);
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.Text = 'Export';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.FileNameArea_2 = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Equalizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end